classdef bonus_opportunity_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SharpenCheckBox               matlab.ui.control.CheckBox
        orLabel                       matlab.ui.control.Label
        AdjustColorLevelsLabel        matlab.ui.control.Label
        magentaSlider                 matlab.ui.control.Slider
        magentaSliderLabel            matlab.ui.control.Label
        yellowSlider                  matlab.ui.control.Slider
        yellowSliderLabel             matlab.ui.control.Label
        FilterButton                  matlab.ui.control.Button
        PredefinedImagesLabel         matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        LoadButton                    matlab.ui.control.Button
        BrightenCheckBox              matlab.ui.control.CheckBox
        tealSlider                    matlab.ui.control.Slider
        tealSliderLabel               matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        ErrorLabel                    matlab.ui.control.Label
        BeforeLabel                   matlab.ui.control.Label
        ImageEnhancerLabel            matlab.ui.control.Label
        imBeforeAx                    matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        SaveImageLabel                matlab.ui.control.Label
        ImageTypeButtonGroup          matlab.ui.container.ButtonGroup
        tifButton                     matlab.ui.control.RadioButton
        jpgButton                     matlab.ui.control.RadioButton
        pngButton                     matlab.ui.control.RadioButton
        AfterLabel                    matlab.ui.control.Label
        SaveButton                    matlab.ui.control.Button
        WriteImageNameEditField       matlab.ui.control.EditField
        WriteImageNameEditFieldLabel  matlab.ui.control.Label
        imAfterAx                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        % Global Variables because it's Lesson One

        % image based
        img; % image
        brighten = false; % boolean token
        sharpen = false; % boolean token
        teal = 0; % color level (0-0.9)
        magenta = 0; % color level (0-0.9)
        yellow = 0; % color level (0-0.9)

        % save based
        img_save = false;
        filename = "untitled"; 
        filetype = ".png"; 
    end
    
    methods (Access = private)
        
        function changeImageBefore(app)
            imshow(app.img, 'Parent', app.imBeforeAx);
        end

        function changeImageAfter(app)
            % Applies edits to image.
            img_new = app.img;

            if app.brighten
                img_new = imlocalbrighten(img_new);
            end
            if app.sharpen
                img_new = imsharpen(img_new);
            end

            img_new = imadjust(img_new,[ ...
                app.teal app.magenta app.yellow; 1 1 1],[]);

            app.img_save = img_new;
            imshow(img_new, 'Parent', app.imAfterAx);
        end


        function saveImage(app)
            if app.img_save == false
                app.ErrorLabel.Text="Error: No Filtered Image to Save.";
            else
                imwrite(app.img_save, strcat(app.filename, app.filetype));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            if app.img_save == false
                app.ErrorLabel.Text="Error: No Image to Filter.";
            else
                app.ErrorLabel.Text="Filtering...";
                changeImageAfter(app); % Call filter function
                app.ErrorLabel.Text="Filtered!";
            end
            
  
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            app.img = imread(value);
            changeImageBefore(app)
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Code below borrowed from MatLab's HistogramAppExample
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               app.img = imread(f);
               changeImageBefore(app);
            end
            
        end

        % Value changed function: BrightenCheckBox
        function BrightenCheckBoxValueChanged(app, event)
            value = app.BrightenCheckBox.Value;
            app.brighten = value;

        end

        % Value changed function: SharpenCheckBox
        function SharpenCheckBoxValueChanged(app, event)
            value = app.SharpenCheckBox.Value;
            app.sharpen = value;
            
        end

        % Value changed function: tealSlider
        function tealSliderValueChanged(app, event)
            value = app.tealSlider.Value;
            app.teal = value;

        end

        % Value changed function: magentaSlider
        function magentaSliderValueChanged(app, event)
            value = app.magentaSlider.Value;
            app.magenta = value;

        end

        % Value changed function: yellowSlider
        function yellowSliderValueChanged(app, event)
            value = app.yellowSlider.Value;
            app.yellow = value;
      
        end

        % Value changed function: WriteImageNameEditField
        function WriteImageNameEditFieldValueChanged(app, event)
            value = app.WriteImageNameEditField.Value;
            app.filename = value;

        end

        % Selection changed function: ImageTypeButtonGroup
        function ImageTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImageTypeButtonGroup.SelectedObject;
            app.filetype = get(selectedButton, 'Text');
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.ErrorLabel.Text="Saving...";
            saveImage(app);
            app.ErrorLabel.Text="Saved!";

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {296, '1x', 304};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.tealSliderLabel = uilabel(app.LeftPanel);
            app.tealSliderLabel.Text = 'teal';
            app.tealSlider = uislider(app.LeftPanel);
            app.tealSlider.Limits = [0 0.99];
            app.tealSlider.ValueChangedFcn = createCallbackFcn(app, @tealSliderValueChanged, true);
            app.BrightenCheckBox = uicheckbox(app.LeftPanel);
            app.BrightenCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrightenCheckBoxValueChanged, true);
            app.BrightenCheckBox.Text = 'Brighten';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Your Own Image';
            app.DropDown = uidropdown(app.LeftPanel);
            app.DropDown.Items = {'autumn', 'board', 'concordaerial', 'fabric', 'football', 'gantrycrane', 'greens', 'hestain', 'office_1', 'office_2', 'office_3', 'office_4', 'office_5', 'office_6', 'onion', 'pears', 'peppers', 'pillsetc', 'saturn', 'tape', 'tissue', 'westconcordaerial'};
            app.DropDown.ItemsData = {'autumn.tif', 'board.tif', 'concordaerial.png', 'fabric.png', 'football.jpg', 'gantrycrane.png', 'greens.jpg', 'hestain.png', 'office_1.jpg', 'office_2.jpg', 'office_3.jpg', 'office_4.jpg', 'office_5.jpg', 'office_6.jpg', 'onion.png', 'pears.png', 'peppers.png', 'pillsetc.png', 'saturn.png', 'tape.png', 'tissue.png', 'westconcordaerial.png'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'autumn.tif';
            app.PredefinedImagesLabel = uilabel(app.LeftPanel);
            app.PredefinedImagesLabel.Text = 'Choose Test Image';
            app.FilterButton = uibutton(app.LeftPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.yellowSliderLabel = uilabel(app.LeftPanel);
            app.yellowSliderLabel.Text = 'yellow';
            app.yellowSlider = uislider(app.LeftPanel);
            app.yellowSlider.Limits = [0 0.99];
            app.yellowSlider.ValueChangedFcn = createCallbackFcn(app, @yellowSliderValueChanged, true);
            app.magentaSliderLabel = uilabel(app.LeftPanel);
            app.magentaSliderLabel.Text = 'magenta';
            app.magentaSlider = uislider(app.LeftPanel);
            app.magentaSlider.Limits = [0 0.99];
            app.magentaSlider.ValueChangedFcn = createCallbackFcn(app, @magentaSliderValueChanged, true);
            app.AdjustColorLevelsLabel = uilabel(app.LeftPanel);
            app.AdjustColorLevelsLabel.Text = 'Adjust Color Levels';
            app.orLabel = uilabel(app.LeftPanel);
            app.orLabel.Text = 'or';
            app.SharpenCheckBox = uicheckbox(app.LeftPanel);
            app.SharpenCheckBox.ValueChangedFcn = createCallbackFcn(app, @SharpenCheckBoxValueChanged, true);
            app.SharpenCheckBox.Text = 'Sharpen';
            app.CenterPanel = uipanel(app.GridLayout);
            app.imBeforeAx = uiaxes(app.CenterPanel);
            app.imBeforeAx.XTick = [];
            app.imBeforeAx.XTickLabel = {'[ ]'};
            app.imBeforeAx.YTick = [];
            app.ImageEnhancerLabel = uilabel(app.CenterPanel);
            app.ImageEnhancerLabel.Text = {'====='; 'Image Enhancer'; '====='};
            app.BeforeLabel = uilabel(app.CenterPanel);
            app.BeforeLabel.Text = 'Before';
            app.ErrorLabel = uilabel(app.CenterPanel);
            app.ErrorLabel.Text = '---';
            app.RightPanel = uipanel(app.GridLayout);
            app.imAfterAx = uiaxes(app.RightPanel);
            app.imAfterAx.XTick = [];
            app.imAfterAx.XTickLabel = {'[ ]'};
            app.imAfterAx.YTick = [];
            app.WriteImageNameEditFieldLabel = uilabel(app.RightPanel);
            app.WriteImageNameEditFieldLabel.Text = 'Write Image Name';
            app.WriteImageNameEditField = uieditfield(app.RightPanel, 'text');
            app.WriteImageNameEditField.ValueChangedFcn = createCallbackFcn(app, @WriteImageNameEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AfterLabel = uilabel(app.RightPanel);
            app.AfterLabel.Text = 'After';
            app.ImageTypeButtonGroup = uibuttongroup(app.RightPanel);
            app.ImageTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageTypeButtonGroupSelectionChanged, true);
            app.ImageTypeButtonGroup.Title = 'Image Type';
            app.pngButton = uiradiobutton(app.ImageTypeButtonGroup);
            app.pngButton.Text = '.png';
            app.pngButton.Value = true;
            app.jpgButton = uiradiobutton(app.ImageTypeButtonGroup);
            app.jpgButton.Text = '.jpg';
            app.tifButton = uiradiobutton(app.ImageTypeButtonGroup);
            app.tifButton.Text = '.tif';
            app.SaveImageLabel = uilabel(app.RightPanel);
            app.SaveImageLabel.Text = 'Save Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bonus_opportunity_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end